classdef tipose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
        PauseButton  matlab.ui.control.Button
    end

    properties (Access = private)
        RegionList % a List (cell array) of scan region, a region is represented as [x,y,width,height] w.r.t. the underlying image, use rectangle objects as regions
        nextRegion % next region to scan, which can be determined by the code or by human operator interactively
        RegionSize % width or height of scan regions (we always assume width = height)
        SampleSize % width or height of the sample area we can scan with out changing frame. 
        RegionDist % shortest distance between two adjacent scan regions 
        CleanList % a List (cell array) of clean region in the current scan image, use rectangle objects to represent regions
        ScanIm % current scan image;
        state % varible for user to control execution, = 1 (normally executing) = 0 (paused) = 3 (session ended) = 4 (execution ended)
    end

    methods (Access = private)
    
        function scanRegion(app, region)
            % scan sample area defined by region and store image
            % this function save stm scan result as a matrix in private var ScanIm
            % in this prototype, we ignore input arguments (scan resolution)
            
            % In production environment, this function calls scan.vi, passing corresponding 
            % input arguments, then get the scan result back. 
            % But in this prototype, we load saved scan files (.sxm) as substitute for real-time scan results
            % scRgion is a 8*1 cell array, each corresponds to a different channel. 
            
            % this is a pseudo code for testing purpose
            [~,n] = size(app.RegionList);
            if mod(n,2) == 0
                scRgion = sxmopen("../Au/Au/Bi2201#19_072.sxm");
            else
                scRgion = sxmopen("../Au/Au/Au_20200719_01_4.2K_004.sxm");
            end
            % above is a pseudo code for testing purpose
            
            app.ScanIm = scRgion{1}.data; % we use channel 1
            region.EdgeColor = 'r'; % finally, mark the current using scan region in the figure 2 red
        end
        
        function showScan(app)
            % process the scan result of the current scan region and show it in figure 1
            im = app.ScanIm;
            Rscan = imref2d(size(im)); % associate real world spatial reference to the scan image
            Rscan.XWorldLimits = [0, app.RegionSize];
            Rscan.YWorldLimits = [0, app.RegionSize]; 
            imshow(im, Rscan, [],'Parent', app.UIAxes,'Border','tight'); % display scan result
            xlabel(app.UIAxes, 'x/m'); % edit x label
            ylabel(app.UIAxes, 'y/m'); % edit y label
        end
        
        function findClean(app)
            % we need to find usable area (rec in shape) in the current scan image
            % in this prototype, it just gives one fixed region into the clean region list. 
            position = [0.5*app.RegionSize, 0.5*app.RegionSize, 1e-09, 1e-09];
            cleanRg = regionGenerator(app,position,1,'g');
            app.CleanList{end+1} = cleanRg;
        end
    
        function rg = regionGenerator(app,location,axes,color)
            % display a region as a rectangle in specified figure and color
            % axes = 1 : UIAxes; axes = 2 : UIAxes2
            if axes == 1
                rg = rectangle(app.UIAxes,'Position',[location(1)-0.5*location(3),location(2)-0.5*location(4),location(3),location(4)],'EdgeColor',color);
            elseif axes == 2
                rg = rectangle(app.UIAxes2,'Position',[location(1)-0.5*location(3),location(2)-0.5*location(4),location(3),location(4)],'EdgeColor',color);
            end
        end
        
        function scanNext(app)
            % when size of CleanList is zero, go to nextRegion to scan, add the nextRegion to RegionList and update nextRegion itself
            scanRegion(app, app.nextRegion);
            if sum(size(app.RegionList)) ~= 0 
                app.RegionList{end}.Visible = 'off';
            end
            app.RegionList{end+1} = app.nextRegion;
            updateNext(app);
        end

        function updateNext(app)
            % create an new region object and pass reference to the app.nextRegion variable
            % the creation should be based on the current RegionList.
            [~,n] = size(app.RegionList); % version 0.0 implementation, read the size of RegionList
            if n == 0 % empty Region list, we create an intial nextRegion var in the sample image center
                location = [0.5*app.SampleSize, 0.5*app.SampleSize, app.RegionSize, app.RegionSize];
            else
                % non empty Region list, we should create a new region based on the current nextregion and n;
                location = app.nextRegion.Position;
                location(1) = location(1)+0.5*location(3);
                location(2) = location(2)+0.5*location(4); % recover the location info from region object
                for i = 1:ceil(sqrt(n))
                    if n > 4*i*i-4*i+1
                        continue;
                    elseif 4*i*i-6*i+3 <= n
                        location(1) = location(1) + app.RegionDist; % update nextRegion
                        break;
                    elseif 4*i*i-8*i+5 <= n
                        location(2) = location(2) + app.RegionDist; % update nextRegion
                        break;
                    elseif 4*i*i-10*i+7 <= n
                        location(1) = location(1) - app.RegionDist; % update nextRegion
                        break;
                    elseif 4*i*i-12*i+10 <= n
                        location(2) = location(2) - app.RegionDist; % update nextRegion
                        break;
                    end
                end
                if location(1) > (app.SampleSize-0.5*app.RegionSize) || location(2) > (app.SampleSize-0.5*app.RegionSize)
                    app.state = 3;
                elseif location(1) < 0.5*app.RegionSize || location(2) < 0.5*app.RegionSize
                    app.state = 3;
                end
            end
            
            if app.state ~= 3 % this session ended, there is no need to generate the next scan region
                app.nextRegion = regionGenerator(app, location, 2, 'g'); % generate new nextregion and pass reference to nextRegion
            end
        end
    
        function measure(app)
            % do neccessary measurements in clean area (defined by the current CleanList
            % pop a clean region from CleanList
            % do work
            % loop untill CleanList is empty
            % call changeRegion(app) (optional)
            pause(0.1) % in this testing version, we just wait for 1 second instead of doing actual measurement. 
            app.CleanList = {}; % it measures in clean area and renders it dirty
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this function initialize our app. 

            % initialize private variables
            app.RegionList = {};
            app.CleanList = {};
            app.RegionSize = 5e-9;
            app.RegionDist = 15e-9;
            app.state = 0;
            
            % load and display sample image & info
            sc = sxmopen("../Au/Au/Au_20200719_01_4.2K_010.sxm"); % display sample image
            im = sc{1}.data;
            Rim = imref2d(size(im));
            % app.SampleSize = sc{1}.width;
            app.SampleSize = 10e-08;
            Rim.XWorldLimits = [0, app.SampleSize];
            Rim.YWorldLimits = [0, app.SampleSize];
            imshow(im, Rim, [], 'Parent',app.UIAxes2,'Border','tight');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % buttons control the calibration secession in the current sample image
            % user should be able to start, stop, abort the current calibration secession
            % this startbutton starts the calibration secession
            % in this prototype, we assmue that we only start from beginning.
            % get and display nextRegion
            app.state = 1;
            if sum(size(app.RegionList)) == 0
                updateNext(app);
            end
            pause(0.1);
            while app.state == 1
                % scan the nextRegion;
                scanNext(app);
                showScan(app);
                % build CleanList and display
                findClean(app);
                % do calibration on the clean area
                measure(app);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(0.1);
            app.state = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tipose

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end